classdef mainInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NmerodepalavraserradasEditFieldLabel  matlab.ui.control.Label
        NmerodepalavraserradasEditField  matlab.ui.control.NumericEditField
        ComputarButton              matlab.ui.control.Button
        ExecutarButton              matlab.ui.control.Button
        ProximoButton               matlab.ui.control.Button
        StatusdeexecuoPanel         matlab.ui.container.Panel
        udioemexecuoTextAreaLabel   matlab.ui.control.Label
        udioemexecuoTextArea        matlab.ui.control.TextArea
        StatusLampLabel             matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        VerdeudioexecutandoVermelhoOuvintepronunciandoLabel  matlab.ui.control.Label
        OrdemdeexecuodosudiosPanel  matlab.ui.container.Panel
        AdicionarlistaButton        matlab.ui.control.Button
        listBoxOrdemExec            matlab.ui.control.ListBox
        nomeLista                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        FolderFullPath % Description
    end
    

    methods (Access = private)

        % Button pushed function: AdicionarlistaButton
        function AdicionarlistaButtonPushed(app, event)
            % Seleciona pasta via UI e lê arquivos .WAV
            folderPath = uigetdir('','Selecione pasta com arquivos .wav da lista de sentenças.');
            cd(folderPath)
            addpath(genpath(folderPath))
            app.FolderFullPath = folderPath;
            listaAudios = dir('*.wav');
            
            
            % Atualiza listBox com arquivos WAV da pasta selecionada
            app.listBoxOrdemExec.Items = {listaAudios.name};
            app.listBoxOrdemExec.ItemsData = 1:length({listaAudios.name});
            % Seleciona primeiro audio para execução
            app.listBoxOrdemExec.Value = 1;
            % Lê nome da pasta e plccxota na interface gráfica para controle
            [filepath,FolderName,ext] = fileparts(folderPath);
            app.nomeLista.Text = FolderName;
            app.ExecutarButton.Enable = 'on';
        end

        % Button pushed function: ComputarButton
        function ComputarButtonPushed(app, event)
            % Habilita troca para próximo audio
            app.ProximoButton.Enable = 'on';
            fprintf('Frases corretas para audio %s = %.0f \n',app.listBoxOrdemExec.Items{app.listBoxOrdemExec.Value},app.NmerodepalavraserradasEditField.Value)
        end

        % Button pushed function: ExecutarButton
        function ExecutarButtonPushed(app, event)
            % Carrega dados do arquivo
            fileName = app.listBoxOrdemExec.Items(app.listBoxOrdemExec.Value);
            fullFileName = fullfile(app.FolderFullPath,fileName);
            
            app.udioemexecuoTextArea.Value = fileName; % TROCAR DEPOIS PARA INFO DA METADATA
            
            % Le audio e reproduz
            [signal, fs] = audioread(fullFileName{1});
            player = audioplayer(signal,fs);
            player.play
            app.StatusLamp.Color = 'green';
            pause(length(signal)/fs) % intervalo para reprodução
            app.StatusLamp.Color = 'red';
            app.ComputarButton.Enable = 'on';
        end

        % Button pushed function: ProximoButton
        function ProximoButtonPushed(app, event)
            % Atualiza contador de lista
            app.listBoxOrdemExec.Value = app.listBoxOrdemExec.Value + 1;
            % Desabilita botão 'proximo audio' até reprodução do audio atual
            app.ProximoButton.Enable = 'off';
            app.ComputarButton.Enable = 'off';            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.NmerodepalavraserradasEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodepalavraserradasEditFieldLabel.Text = 'Número de palavras erradas';
            app.NmerodepalavraserradasEditField = uieditfield(app.UIFigure, 'numeric');
            app.ComputarButton = uibutton(app.UIFigure, 'push');
            app.ComputarButton.ButtonPushedFcn = createCallbackFcn(app, @ComputarButtonPushed, true);
            app.ComputarButton.Enable = 'off';
            app.ComputarButton.Text = 'Computar';
            app.ExecutarButton = uibutton(app.UIFigure, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @ExecutarButtonPushed, true);
            app.ExecutarButton.Enable = 'off';
            app.ExecutarButton.Text = 'Executar';
            app.ProximoButton = uibutton(app.UIFigure, 'push');
            app.ProximoButton.ButtonPushedFcn = createCallbackFcn(app, @ProximoButtonPushed, true);
            app.ProximoButton.Enable = 'off';
            app.ProximoButton.Text = 'Próximo';
            app.StatusdeexecuoPanel = uipanel(app.UIFigure);
            app.StatusdeexecuoPanel.Title = 'Status de execução';
            app.udioemexecuoTextAreaLabel = uilabel(app.StatusdeexecuoPanel);
            app.udioemexecuoTextAreaLabel.Text = 'Áudio em execução';
            app.udioemexecuoTextArea = uitextarea(app.StatusdeexecuoPanel);
            app.StatusLampLabel = uilabel(app.StatusdeexecuoPanel);
            app.StatusLampLabel.Text = 'Status: ';
            app.StatusLamp = uilamp(app.StatusdeexecuoPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoLabel = uilabel(app.StatusdeexecuoPanel);
            app.VerdeudioexecutandoVermelhoOuvintepronunciandoLabel.Text = {'Verde  - Áudio executando '; 'Vermelho   -  Ouvinte pronunciando'};
            app.OrdemdeexecuodosudiosPanel = uipanel(app.UIFigure);
            app.OrdemdeexecuodosudiosPanel.Title = 'Ordem de execução dos áudios';
            app.AdicionarlistaButton = uibutton(app.OrdemdeexecuodosudiosPanel, 'push');
            app.AdicionarlistaButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionarlistaButtonPushed, true);
            app.AdicionarlistaButton.Text = 'Adicionar lista';
            app.listBoxOrdemExec = uilistbox(app.OrdemdeexecuodosudiosPanel);
            app.listBoxOrdemExec.Items = {};
            app.listBoxOrdemExec.Enable = 'off';
            app.listBoxOrdemExec.Value = {};
            app.nomeLista = uilabel(app.OrdemdeexecuodosudiosPanel);
            app.nomeLista.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mainInterface()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end